classdef find_flexion_extension_events < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        LogTextArea          matlab.ui.control.TextArea
        Label                matlab.ui.control.Label
        SaveEventsButton     matlab.ui.control.Button
        ResetLimitsButton    matlab.ui.control.Button
        AddLowPeaksButton    matlab.ui.control.Button
        Panel                matlab.ui.container.Panel
        LoadDataButton       matlab.ui.control.Button
        NextTrialButton      matlab.ui.control.Button
        PreviousTrialButton  matlab.ui.control.Button
        Label1               matlab.ui.control.Label
        AddHighPeaksButton   matlab.ui.control.Button
        UIAxes               matlab.ui.control.UIAxes
    end


    properties (Access = public)
        subject_id;
        time;
        Exp_data;
        trial;
        Trials_encoder_events;
        
        start_beep
        pressure_change_time;
        start_move;
        finish_beep;
        score_press_time;

        XLimits;
        
        trial_pks_high_peaks;
        trial_locs_high_peaks;
        trial_pks_low_peaks;
        trial_locs_low_peaks;

        excluded_high_peaks;
        excluded_low_peaks;

        high_peak_markers;
        low_peak_markers;
    end
    
    properties (Access = private)
        IsAppClosed logical = false; % To check if the app is closed
    end


    methods (Access = private)

        % Function to update the trial plot
        function updateTrialPlot(app)
            app.UIAxes.XLim = [app.time(app.start_beep(app.trial)) - 1, ...
                app.score_press_time(app.trial) + 1];

            % Update the trial label
            app.Label1.Text = sprintf('Trial %d of %d', app.trial, size(app.XLimits, 1));
        end

        % Function to handle peak selection
        function onPlotClick(app, ~, event)
            click_position = app.UIAxes.CurrentPoint(1, 1);
            click_y = app.UIAxes.CurrentPoint(1, 2);

            % Check if the click is within the axes limits
            if click_position >= app.UIAxes.XLim(1) && click_position <= app.UIAxes.XLim(2) && ...
                    click_y >= app.UIAxes.YLim(1) && click_y <= app.UIAxes.YLim(2)
                
                % Determine if the click is near a high peak
                [~, idx_high] = min(abs(app.time(app.trial_locs_high_peaks) - click_position));
                if abs(app.time(app.trial_locs_high_peaks(idx_high)) - click_position) < 0.5 && ...
                        abs(app.trial_pks_high_peaks(idx_high) - click_y) < 5
                    if ismember(idx_high, app.excluded_high_peaks)
                        app.excluded_high_peaks(app.excluded_high_peaks == idx_high) = [];
                    else
                        app.excluded_high_peaks = [app.excluded_high_peaks, idx_high];
                    end
                end

                % Determine if the click is near a low peak
                [~, idx_low] = min(abs(app.time(app.trial_locs_low_peaks) - click_position));
                if abs(app.time(app.trial_locs_low_peaks(idx_low)) - click_position) < 0.5 && ...
                        abs(app.trial_pks_low_peaks(idx_low) - click_y) < 5
                    if ismember(idx_low, app.excluded_low_peaks)
                        app.excluded_low_peaks(app.excluded_low_peaks == idx_low) = [];
                    else
                        app.excluded_low_peaks = [app.excluded_low_peaks, idx_low];
                    end
                end

                % Update the plot to reflect selections
                updatePeakMarkers(app);
            end
        end

        
        % Function to plot and update peak markers
        function updatePeakMarkers(app)
            % Update high peak markers
            for i = 1:length(app.high_peak_markers)
                if ismember(i, app.excluded_high_peaks)
                    % If the peak is excluded, highlight it with black edge
                    % app.high_peak_markers(i).MarkerFaceColor = app.AddHighPeaksButton.BackgroundColor;
                    app.high_peak_markers(i).MarkerEdgeColor = 'k';
                    app.high_peak_markers(i).LineWidth = 1;
                else
                    % Otherwise, set it to the default color
                    app.high_peak_markers(i).MarkerFaceColor = app.AddHighPeaksButton.BackgroundColor;
                    app.high_peak_markers(i).MarkerEdgeColor = app.AddHighPeaksButton.BackgroundColor;
                end
            end
        
            % Update low peak markers
            for i = 1:length(app.low_peak_markers)
                if ismember(i, app.excluded_low_peaks)
                    % If the peak is excluded, highlight it with black edge
                    app.low_peak_markers(i).MarkerEdgeColor = 'k';
                    app.low_peak_markers(i).LineWidth = 1;
                else
                    % Otherwise, set it to the default color
                    app.low_peak_markers(i).MarkerFaceColor = app.AddLowPeaksButton.BackgroundColor;
                    app.low_peak_markers(i).MarkerEdgeColor = app.AddLowPeaksButton.BackgroundColor;
                end
            end
        end

        function resetXLim(app)
            % Reset XLim to the stored value for the current trial
            app.UIAxes.XLim = [app.time(app.start_beep(app.trial)) - 1, ...
                app.score_press_time(app.trial) + 1];
            app.UIAxes.YLim = [-100 30];
        end

       
        function onCloseRequest(app)
            delete(app.UIFigure); % Close the app window
        end

    end
    
    methods (Access = public)
        function initializeApp(app, subject_id, All_Experiment_time, ...
                All_Experiment, start_beep, ...
                pressure_change_time_Expdata, start_move, ...
                finish_beep, score_press_time_Expdata, ...
                XLimits, trial_pks_high_peaks, trial_locs_high_peaks, ...
                trial_pks_low_peaks, trial_locs_low_peaks, ...
                Trials_encoder_events)
            
            app.subject_id = subject_id;

            app.time = All_Experiment_time; 
            app.Exp_data = All_Experiment;
           
            app.start_beep           = start_beep;
            app.pressure_change_time = pressure_change_time_Expdata;
            app.start_move           = start_move;
            app.finish_beep          = finish_beep;
            app.score_press_time     = score_press_time_Expdata;

            app.XLimits = XLimits;

            app.trial_pks_high_peaks = trial_pks_high_peaks;
            app.trial_locs_high_peaks = trial_locs_high_peaks;
            app.trial_pks_low_peaks = trial_pks_low_peaks;
            app.trial_locs_low_peaks = trial_locs_low_peaks;

            app.Trials_encoder_events = Trials_encoder_events;
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % Assign the CloseRequestFcn to the UIFigure
            app.UIFigure.CloseRequestFcn = @(src, event)onCloseRequest(app);
            
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            % % Retrieve Experiment data from the base workspace
            % app.subject_id = evalin('base', 'subject_id');
            % 
            % app.time = evalin('base', 'All_Experiment_time'); 
            % app.Exp_data = evalin('base', 'All_Experiment');
            % 
            % app.start_beep = evalin('base', 'start_beep');
            % app.pressure_change_time = evalin('base', 'pressure_change_time_Expdata');
            % app.start_move = evalin('base', 'start_move');
            % app.finish_beep = evalin('base', 'finish_beep');
            % app.score_press_time = evalin('base', 'score_press_time_Expdata');
            % 
            % app.XLimits = evalin('base', 'XLimits');
            % 
            % app.trial_pks_high_peaks = evalin('base', 'trial_pks_high_peaks');
            % app.trial_locs_high_peaks = evalin('base', 'trial_locs_high_peaks');
            % app.trial_pks_low_peaks = evalin('base', 'trial_pks_low_peaks');
            % app.trial_locs_low_peaks = evalin('base', 'trial_locs_low_peaks');
            % 
            % app.Trials_encoder_events = evalin('base', 'Trials_encoder_events');
            app.trial = 0; % Reset trial to 0

            app.excluded_high_peaks = [];
            app.excluded_low_peaks = [];

            % Initialize arrays to hold the marker handles
            app.high_peak_markers = gobjects(1, length(app.trial_locs_high_peaks));
            app.low_peak_markers = gobjects(1, length(app.trial_locs_low_peaks));

            
            % Plot the data
            plot(app.UIAxes, app.time, app.Exp_data(1,:), 'Color', 0.3*[1 1 1]);
            app.UIAxes.XLim = [min(app.time) max(app.time)];
            hold(app.UIAxes, 'on')

            h1 = xline(app.UIAxes, ...
                app.time(app.start_beep), ...
                'Color', 'k', ...
                'LineStyle', '-', 'LineWidth', 1);

            h2 = xline(app.UIAxes, ...
                app.pressure_change_time, ...
                'Color', [214, 121, 255]/255, ...
                'LineStyle', '--', 'LineWidth', 1);

            h3 = xline(app.UIAxes, ...
                app.time(app.start_move), ...
                'Color', 0.7*app.AddHighPeaksButton.BackgroundColor, ...
                'LineStyle', '-', 'LineWidth', 1);

            h4 = xline(app.UIAxes, ...
                app.time(app.finish_beep), ...
                'Color', 0.7*app.AddLowPeaksButton.BackgroundColor, ...
                'LineStyle', '-', 'LineWidth', 1);

            h5 = xline(app.UIAxes, ...
                app.score_press_time, ...
                'Color', 'k', ...
                'LineStyle', '--', 'LineWidth', 1);

            % Plot high peak markers individually
            for i = 1:length(app.trial_locs_high_peaks)
                app.high_peak_markers(i) = plot(app.UIAxes, ...
                    app.time(app.trial_locs_high_peaks(i)), ...
                    app.trial_pks_high_peaks(i), ...
                    'LineStyle','none', ...
                    'Marker','v', 'MarkerSize', 8, ...
                    'MarkerFaceColor', ...
                    app.AddHighPeaksButton.BackgroundColor, ...
                    'MarkerEdgeColor', ...
                    app.AddHighPeaksButton.BackgroundColor);
            end

            % Plot low peak markers individually
            for i = 1:length(app.trial_locs_low_peaks)
                app.low_peak_markers(i) = plot(app.UIAxes, ...
                    app.time(app.trial_locs_low_peaks(i)), app.trial_pks_low_peaks(i), ...
                    'LineStyle','none', ...
                    'Marker','^', 'MarkerSize', 8, ...
                    'MarkerFaceColor', ...
                    app.AddLowPeaksButton.BackgroundColor, ...
                    'MarkerEdgeColor', ...
                    app.AddLowPeaksButton.BackgroundColor); 
            end
            
            legend(app.UIAxes, [h1(1), h2(1), h3(1), h4(1), h5(1)], ...
                {'Trial Start', 'Pressure Change', 'Start Movement', 'Stop Movement', 'Score Press'}, ...
                'Location', 'south', 'Orientation', 'horizontal') 

            app.UIAxes.YLim = [-100 30];

            % Disable the Load Data button
            app.LoadDataButton.Enable = 'off';

            % Update the trial label
            app.Label1.Text = sprintf('Trial %d of %d', app.trial, size(app.XLimits, 1));

            % % Add click callback to the plot
            % app.UIAxes.ButtonDownFcn = @(src, event)onPlotClick(app, src, event);

            % Add click callback to the plot
            app.UIFigure.WindowButtonDownFcn = @(src, event)onPlotClick(app, src, event);

        end

        % Button pushed function: NextTrialButton
        function NextTrialButtonPushed(app, event)
            
            if app.trial < length(app.Trials_encoder_events)

                if app.trial > 0
                    AddHighPeaksButtonPushed2(app, event)
                    AddLowPeaksButtonPushed2(app, event)
                end
                
                app.trial = app.trial + 1;
                updateTrialPlot(app);

                    
            end
            
            
        end

        % Button pushed function: PreviousTrialButton
        function PreviousTrialButtonPushed(app, event)
            
            if app.trial > 1
                app.trial = app.trial - 1;
                updateTrialPlot(app);
            end

        end

        % Button pushed function: AddHighPeaksButton
        function AddHighPeaksButtonPushed2(app, event)
            
            current_trial = app.Trials_encoder_events{1, app.trial};

            [~, first_high_peak_indx] = min(abs(app.trial_locs_high_peaks - app.start_move(app.trial)));
            [~, last_high_peak_indx]  = min(abs(app.trial_locs_high_peaks - app.finish_beep(app.trial)));

            high_peak_indices = ...
                setdiff(first_high_peak_indx:last_high_peak_indx, ...
                app.excluded_high_peaks);

            current_trial.high_peaks.index = app.trial_locs_high_peaks(high_peak_indices);
            current_trial.high_peaks.time  = app.time(app.trial_locs_high_peaks(high_peak_indices));
            current_trial.high_peaks.value = app.trial_pks_high_peaks(high_peak_indices);

            app.Trials_encoder_events{1, app.trial} = current_trial;

            % Update the Trials_encoder_events in the base workspace
            assignin('base', 'Trials_encoder_events', app.Trials_encoder_events);

            % Log the action
            logMessage = sprintf('Trial %d, high peaks added.', app.trial);
            
            % Keep only the last five messages
            if numel(app.LogTextArea.Value) >= 6
                app.LogTextArea.Value = app.LogTextArea.Value(end-5:end);
            end

            app.LogTextArea.Value{end+1} = logMessage;

            
        end

        % Button pushed function: AddLowPeaksButton
        function AddLowPeaksButtonPushed2(app, event)
            
            current_trial = app.Trials_encoder_events{1, app.trial};

            [~, first_low_peak_indx] = min(abs(app.trial_locs_low_peaks - app.start_move(app.trial)));
            [~, last_low_peak_indx]  = min(abs(app.trial_locs_low_peaks - app.finish_beep(app.trial)));

            low_peak_indices = ...
                setdiff(first_low_peak_indx:last_low_peak_indx, ...
                app.excluded_low_peaks);

            current_trial.low_peaks.index = app.trial_locs_low_peaks(low_peak_indices);
            current_trial.low_peaks.time  = app.time(app.trial_locs_low_peaks(low_peak_indices));
            current_trial.low_peaks.value = app.trial_pks_low_peaks(low_peak_indices);
            
            app.Trials_encoder_events{1, app.trial} = current_trial;

            % Update the Trials_encoder_events in the base workspace
            assignin('base', 'Trials_encoder_events', app.Trials_encoder_events);

            % Log the action
            logMessage = sprintf('Trial %d, low peaks added.', app.trial);
            
            % Keep only the last two messages
            if numel(app.LogTextArea.Value) >= 6
                app.LogTextArea.Value = app.LogTextArea.Value(end-5:end);
            end
            
            app.LogTextArea.Value{end+1} = logMessage;

            
        end

        % Button pushed function: ResetLimitsButton
        function ResetLimitsButtonPushed(app, event)
            resetXLim(app);
        end

        % Button pushed function: SaveEventsButton
        function SaveEventsButtonPushed(app, event)
            
            % Prompt user to confirm if all trials are complete
            choice = questdlg('Have you completed all the trials?', ...
                'Save Confirmation', ...
                'Yes', 'No', 'No');
            
            % Handle response
            switch choice
                case 'Yes'
                    % Set default file name
                    defaultFileName = ['sub-', num2str(app.subject_id), '_Trials_encoder_events.mat'];
                    
                    % Prompt user to select a file location with the default file name
                    [file, path] = uiputfile('*.mat', 'Save Events As', defaultFileName);
                    if isequal(file, 0) || isequal(path, 0)
                        % User canceled the save operation
                        uialert(app.UIFigure, 'Save operation canceled.', 'Save Canceled');
                    else
                        % Save the Trials_encoder_events to the specified file
                        Trials_encoder_events = app.Trials_encoder_events;
                        save(fullfile(path, file), 'Trials_encoder_events', '-v7.3');
                        
                        
                        % Create a new figure and replicate the plot
                        tempFig = figure(); % Create an invisible figure
                        newAxes = axes(tempFig); % Create new axes in the figure
                        
                        % Copy the relevant plot data
                        plot(newAxes, app.time, app.Exp_data(1, :));
                        hold(newAxes, 'on');

                        xline(newAxes, app.time(app.start_move), ...
                            'Color', 0.7 * app.AddHighPeaksButton.BackgroundColor, ...
                            'LineStyle', '-', 'LineWidth', 2);
                        xline(newAxes, app.time(app.finish_beep), ...
                            'Color', 0.7 * app.AddLowPeaksButton.BackgroundColor, ...
                            'LineStyle', '-', 'LineWidth', 2);
                        xline(newAxes, app.time(app.start_move) - 2, ...
                            'Color', 'k', 'LineStyle', '--', 'LineWidth', 1);

                        plot(newAxes, app.time(app.trial_locs_high_peaks), ...
                            app.trial_pks_high_peaks, ...
                            'v', 'MarkerSize', 8, ...
                            'MarkerFaceColor', app.AddHighPeaksButton.BackgroundColor, ...
                            'MarkerEdgeColor', app.AddHighPeaksButton.BackgroundColor);

                        plot(newAxes, app.time(app.trial_locs_low_peaks), ...
                            app.trial_pks_low_peaks, ...
                            '^', 'MarkerSize', 8, ...
                            'MarkerFaceColor', app.AddLowPeaksButton.BackgroundColor, ...
                            'MarkerEdgeColor', app.AddLowPeaksButton.BackgroundColor);
                        
                        plot(newAxes, app.time(app.trial_locs_high_peaks(app.excluded_high_peaks)), ...
                            app.trial_pks_high_peaks(app.excluded_high_peaks),...
                            'v', 'MarkerSize', 8, ...
                            'MarkerFaceColor', app.AddHighPeaksButton.BackgroundColor, ...
                            'MarkerEdgeColor', 'k', 'LineWidth', 2);

                        plot(newAxes, app.time(app.trial_locs_low_peaks(app.excluded_low_peaks)), ...
                            app.trial_pks_low_peaks(app.excluded_low_peaks),...
                            '^', 'MarkerSize', 8, ...
                            'MarkerFaceColor', app.AddLowPeaksButton.BackgroundColor, ...
                            'MarkerEdgeColor', 'k', 'LineWidth', 2);

                        % Set the limits and labels
                        xlabel(newAxes, 'time');
                        ylabel(newAxes, 'Angle (degree)');
                        newAxes.XLim = [app.time(1), app.time(end)];
                        newAxes.YLim = [-100, 20];
                        newAxes.XGrid = 'on';
                        newAxes.XMinorGrid = 'on';
                        newAxes.YGrid = 'on';
                        newAxes.YMinorGrid = 'on';

                        % Save the new figure
                        figFile = fullfile(path, strrep(file, '.mat', '.fig'));
                        savefig(tempFig, figFile);
                        
                        % Close the temporary figure
                        close(tempFig);

                        uialert(app.UIFigure, 'Events and figure saved successfully.', 'Save Success');
                    end
                case 'No'
                    % User chose not to save
                    uialert(app.UIFigure, 'Please complete all trials before saving.', 'Save Aborted');
            end
            

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Encoder data with high and low peaks')
            xlabel(app.UIAxes, 'time')
            ylabel(app.UIAxes, 'Angle (degree)')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.AddHighPeaksButton = uibutton(app.GridLayout, 'push');
            app.AddHighPeaksButton.ButtonPushedFcn = createCallbackFcn(app, @AddHighPeaksButtonPushed2, true);
            app.AddHighPeaksButton.Text = 'Add High Peaks';
            app.Label1 = uilabel(app.GridLayout);
            app.Label1.Text = 'Trial 0 of 120';
            app.PreviousTrialButton = uibutton(app.GridLayout, 'push');
            app.PreviousTrialButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousTrialButtonPushed, true);
            app.PreviousTrialButton.Text = 'Previous Trial';
            app.NextTrialButton = uibutton(app.GridLayout, 'push');
            app.NextTrialButton.ButtonPushedFcn = createCallbackFcn(app, @NextTrialButtonPushed, true);
            app.NextTrialButton.Text = 'Next Trial';
            app.LoadDataButton = uibutton(app.GridLayout, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.Panel = uipanel(app.GridLayout);
            app.AddLowPeaksButton = uibutton(app.GridLayout, 'push');
            app.AddLowPeaksButton.ButtonPushedFcn = createCallbackFcn(app, @AddLowPeaksButtonPushed2, true);
            app.AddLowPeaksButton.Text = 'Add Low Peaks';
            app.ResetLimitsButton = uibutton(app.GridLayout, 'push');
            app.ResetLimitsButton.ButtonPushedFcn = createCallbackFcn(app, @ResetLimitsButtonPushed, true);
            app.ResetLimitsButton.Text = 'Reset Limits';
            app.SaveEventsButton = uibutton(app.GridLayout, 'push');
            app.SaveEventsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveEventsButtonPushed, true);
            app.SaveEventsButton.Text = 'Save Events';
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = '';
            app.LogTextArea = uitextarea(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = find_flexion_extension_events

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end